classdef LSS_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DoFArmControlUIFigure  matlab.ui.Figure
        BERTEnableSwitch       matlab.ui.control.Switch
        BERTEnableSwitchLabel  matlab.ui.control.Label
        BERTSensorsPanel       matlab.ui.container.Panel
        Q3Gauge                matlab.ui.control.SemicircularGauge
        Q3GaugeLabel           matlab.ui.control.Label
        Q2Gauge                matlab.ui.control.NinetyDegreeGauge
        Q2GaugeLabel           matlab.ui.control.Label
        Q1Gauge                matlab.ui.control.NinetyDegreeGauge
        Q1GaugeLabel           matlab.ui.control.Label
        ConnectedLamp          matlab.ui.control.Lamp
        ConnectedLampLabel     matlab.ui.control.Label
        ConnectButton          matlab.ui.control.Button
        BERTCOMEditField       matlab.ui.control.NumericEditField
        BERTCOMEditFieldLabel  matlab.ui.control.Label
        HUECOMEditField        matlab.ui.control.NumericEditField
        HUECOMEditFieldLabel   matlab.ui.control.Label
        ValidLamp              matlab.ui.control.Lamp
        ValidLampLabel         matlab.ui.control.Label
        HUEBERTControlLabel    matlab.ui.control.Label
        PositionPanel          matlab.ui.container.Panel
        SENDButton             matlab.ui.control.Button
        SPDSlider              matlab.ui.control.Slider
        SPDSliderLabel         matlab.ui.control.Label
        YmmSlider              matlab.ui.control.Slider
        YmmSliderLabel         matlab.ui.control.Label
        ZmmSlider              matlab.ui.control.Slider
        ZmmSliderLabel         matlab.ui.control.Label
        XmmSlider              matlab.ui.control.Slider
        XmmSliderLabel         matlab.ui.control.Label
    end


    properties (Access = public)
        Property % Description
        ardHUE
        ardBERT
        t
    end

    methods (Access = public)

        function results = readBERT(app, obj, event)
            if(app.ardBERT.BytesAvailable>0)

                sensorstring = readline(app.ardBERT);
                sensorvalues = split(sensorstring,',');
                disp(sensorvalues)

                if(not(isempty(sensorvalues)) && length(sensorvalues)==4)
                    if('V' == sensorvalues(1))

                        app.Q1Gauge.Value = str2double(sensorvalues(2));
                        app.Q2Gauge.Value = str2double(sensorvalues(3));
                        app.Q3Gauge.Value = str2double(sensorvalues(4));
                        results = sensorvalues;
                        steps = voltage2step([app.Q1Gauge.Value, app.Q2Gauge.Value , app.Q3Gauge.Value]);
                        disp(steps);
                        if((true== app.BERTEnableSwitch.Value) && ALL(isreal(steps(:))))
                            app.ValidLamp.Color = [0 1 0];

                            send2ard(app.ardHUE,'p',[steps, round(app.SPDSlider.Value)]);
                            %                         waitforHUE(app.ardHUE,"E");
                        elseif (not(isreal(steps(:))))
                            app.ValidLamp.Color = [1 0 0];
                            disp("Not Valid Position");
                        end
                    end
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.ConnectedLamp.Color = [1 0 0];
            app.t = timer("TimerFcn",@app.readBERT, "ExecutionMode",'fixedRate',"Period",0.1);

        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)

            clear app.ardHUE app.ardBERT
            %Connect HUE
            app.ardHUE=serialport('COM'+ string(app.HUECOMEditField.Value) ,9600,'Parity','none');
            %             waitforHUE(app.ardHUE,"R");
            flush(app.ardHUE,"input");
            flush(app.ardHUE,"output");
            disp("Connected to HUE")

            app.ardBERT=serialport('COM'+ string(app.BERTCOMEditField.Value), 9600,'Parity','none');
            %             waitforHUE(app.ardHUE,"R");
            flush(app.ardBERT,"input");
            flush(app.ardBERT,"output");
            disp("Connected to BERT")
            app.ConnectedLamp.Color = [0 1 0];
            app.t.start

        end

        % Button pushed function: SENDButton
        function SENDButtonPushed(app, event)

            X = app.XmmSlider.Value;
            Y = app.YmmSlider.Value;
            Z = app.ZmmSlider.Value;
            S = round(app.SPDSlider.Value);
            steps = [xyz2step([X,Y,Z]), S];

            if(isreal(steps(:)))
                disp(steps);
                app.ValidLamp.Color = [0 1 0];
                send2ard(app.ardHUE,'p',steps);
                %                 waitforHUE(app.ardHUE,"E");
            else
                app.ValidLamp.Color = [1 0 0];
                disp("Not Valid Position");
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DoFArmControlUIFigure = uifigure('Visible', 'off');
            app.DoFArmControlUIFigure.Name = '4 DoF Arm Control';
            app.PositionPanel = uipanel(app.DoFArmControlUIFigure);
            app.PositionPanel.Title = 'Position';
            app.XmmSliderLabel = uilabel(app.PositionPanel);
            app.XmmSliderLabel.Text = 'X (mm)';
            app.XmmSlider = uislider(app.PositionPanel);
            app.XmmSlider.Limits = [50 400];
            app.XmmSlider.Value = 200;
            app.ZmmSliderLabel = uilabel(app.PositionPanel);
            app.ZmmSliderLabel.Text = 'Z (mm)';
            app.ZmmSlider = uislider(app.PositionPanel);
            app.ZmmSlider.Limits = [-100 100];
            app.YmmSliderLabel = uilabel(app.PositionPanel);
            app.YmmSliderLabel.Text = 'Y (mm)';
            app.YmmSlider = uislider(app.PositionPanel);
            app.YmmSlider.Limits = [-200 200];
            app.SPDSliderLabel = uilabel(app.PositionPanel);
            app.SPDSliderLabel.Text = 'SPD ';
            app.SPDSlider = uislider(app.PositionPanel);
            app.SPDSlider.Limits = [1 20];
            app.SPDSlider.Value = 1;
            app.SENDButton = uibutton(app.PositionPanel, 'push');
            app.SENDButton.ButtonPushedFcn = createCallbackFcn(app, @SENDButtonPushed, true);
            app.SENDButton.Text = 'SEND';
            app.HUEBERTControlLabel = uilabel(app.DoFArmControlUIFigure);
            app.HUEBERTControlLabel.Text = {'HUEBERT'; 'Control'};
            app.ValidLampLabel = uilabel(app.DoFArmControlUIFigure);
            app.ValidLampLabel.Text = 'Valid';
            app.ValidLamp = uilamp(app.DoFArmControlUIFigure);
            app.HUECOMEditFieldLabel = uilabel(app.DoFArmControlUIFigure);
            app.HUECOMEditFieldLabel.Text = 'HUE: COM';
            app.HUECOMEditField = uieditfield(app.DoFArmControlUIFigure, 'numeric');
            app.HUECOMEditField.Value = 9;
            app.BERTCOMEditFieldLabel = uilabel(app.DoFArmControlUIFigure);
            app.BERTCOMEditFieldLabel.Text = 'BERT: COM';
            app.BERTCOMEditField = uieditfield(app.DoFArmControlUIFigure, 'numeric');
            app.BERTCOMEditField.Value = 10;
            app.ConnectButton = uibutton(app.DoFArmControlUIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ConnectedLampLabel = uilabel(app.DoFArmControlUIFigure);
            app.ConnectedLampLabel.Text = 'Connected';
            app.ConnectedLamp = uilamp(app.DoFArmControlUIFigure);
            app.BERTSensorsPanel = uipanel(app.DoFArmControlUIFigure);
            app.BERTSensorsPanel.Title = 'BERT Sensors';
            app.Q1GaugeLabel = uilabel(app.BERTSensorsPanel);
            app.Q1GaugeLabel.Text = 'Q1';
            app.Q1Gauge = uigauge(app.BERTSensorsPanel, 'ninetydegree');
            app.Q1Gauge.Limits = [0 500];
            app.Q2GaugeLabel = uilabel(app.BERTSensorsPanel);
            app.Q2GaugeLabel.Text = 'Q2';
            app.Q2Gauge = uigauge(app.BERTSensorsPanel, 'ninetydegree');
            app.Q2Gauge.Limits = [500 1023];
            app.Q3GaugeLabel = uilabel(app.BERTSensorsPanel);
            app.Q3GaugeLabel.Text = 'Q3';
            app.Q3Gauge = uigauge(app.BERTSensorsPanel, 'semicircular');
            app.Q3Gauge.Limits = [0 1000];
            app.BERTEnableSwitchLabel = uilabel(app.DoFArmControlUIFigure);
            app.BERTEnableSwitchLabel.Text = 'BERTEnable';
            app.BERTEnableSwitch = uiswitch(app.DoFArmControlUIFigure, 'slider');
            app.BERTEnableSwitch.Items = {'0', '1'};
            app.BERTEnableSwitch.Value = '0';
            % Show the figure after all components are created
            app.DoFArmControlUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LSS_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DoFArmControlUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.DoFArmControlUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DoFArmControlUIFigure)
        end
    end
end